classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        FrecuenciaActualLabel  matlab.ui.control.Label
        b1                     matlab.ui.control.Button
        axes                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: b1
        function b1Pushed(app, event)
            Fs = 44100;
            recObj = audiorecorder(44100, 16, 1);
            recordblocking(recObj, 1.7);
            y = getaudiodata(recObj);
            dp = 0.1;
            f = 700;
            Wp=f(1)/(Fs/2);
            [b, a] = cheby1(4,dp,Wp,'low');
            y1= filter(b,a,y);
            L = 0.02*Fs;
            overlap = floor(0.9*L);
            nfft = 2^nextpow2(L);
            [pxx,f] = pwelch(y1,hamming(L),overlap,nfft,Fs);
            pxx1 = 20*log(pxx);
            plot(app.axes,f,pxx1);
            [pks,locs] = findpeaks(pxx,'SortStr','descend','NPeaks',1);
            pk = f(locs);
            pk1 = string(pk);
            app.FrecuenciaActualLabel.Text = "Frecuencia Actual: " + pk1;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axes = uiaxes(app.UIFigure);
            title(app.axes, 'Title')
            xlabel(app.axes, 'X')
            ylabel(app.axes, 'Y')
            zlabel(app.axes, 'Z')
            app.b1 = uibutton(app.UIFigure, 'push');
            app.b1.ButtonPushedFcn = createCallbackFcn(app, @b1Pushed, true);
            app.b1.Tag = 'b1';
            app.b1.Text = 'Precione y emita la nota';
            app.FrecuenciaActualLabel = uilabel(app.UIFigure);
            app.FrecuenciaActualLabel.Text = 'Frecuencia:Actual';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end